classdef cellLinker_Part3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        SetandcontinueButton        matlab.ui.control.Button
        afterLabel                  matlab.ui.control.Label
        beforeLabel                 matlab.ui.control.Label
        ROIafterEditField           matlab.ui.control.EditField
        ROIafterEditFieldLabel      matlab.ui.control.Label
        ROIbeforeEditField          matlab.ui.control.EditField
        ROIbeforeEditFieldLabel     matlab.ui.control.Label
        anatomicallabelButtonGroup  matlab.ui.container.ButtonGroup
        UnknownButton               matlab.ui.control.RadioButton
        NeitherButton               matlab.ui.control.RadioButton
        BothButton                  matlab.ui.control.RadioButton
        mRuby2Button                matlab.ui.control.RadioButton
        eYFPButton                  matlab.ui.control.RadioButton
        mRuby2Label                 matlab.ui.control.Label
        eYFPLabel                   matlab.ui.control.Label
        GCaMPLabel                  matlab.ui.control.Label
        ROILabel                    matlab.ui.control.Label
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        expDir % base directory of analyzed experiment
        iscell1 % iscell: classifier for good ROIs (before dataset)
        stat1 % stat: statistics for each cell (to be used for ROI drawing)
        iscell2 % "" (after dataset)
        stat2
        curcell1
        curcell2
        goodcells1
        goodcells2
        
        roiinfo1
        roiinfo2
        
        centers1
        centers2
        
        textlabels1
        textlabels2
        
        donecells1
    end
    
    methods (Access = private)
        
        function zoom2cell(app)
            zoomrange = 25;
            includerange = 15;
            if app.curcell1>0
                curcell = app.curcell1;
                center = app.centers1(curcell,:);
                othercenters = app.centers2;
            else
                curcell = app.curcell2;
                center = app.centers2(curcell,:);
                othercenters = app.centers1;
            end
            x = center(1);
            y = center(2);
            
            %zoom with limits
            xlim(app.UIAxes,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_2,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_2,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_3,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_3,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_4,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_4,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_5,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_5,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_6,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_6,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_7,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_7,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_8,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_8,[y-zoomrange y+zoomrange])
            
            %only write the text for rois in the very center of the zoomed in image?   
            try
                for i = 1:length(app.textlabels1)
                    delete(app.textlabels1(i));
                end
            end
            try
                for i = 1:length(app.textlabels2)
                    delete(app.textlabels2(i));
                end
            end
            if app.curcell1>0
                app.textlabels1 = text(app.UIAxes,x,y,num2str(curcell),'Color',[1 1 1],'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
            else
                app.textlabels2 = text(app.UIAxes_6,x,y,num2str(curcell),'Color',[1 1 1],'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
            end
            
            xclose = abs(othercenters(:,1)-x)<includerange;
            yclose = abs(othercenters(:,2)-y)<includerange;
            closecells = find(xclose & yclose);
            for i = 1:length(closecells)
                x = othercenters(closecells(i),1);
                y = othercenters(closecells(i),2);
                if app.curcell1>0
                    app.textlabels2(i) = text(app.UIAxes_6,x,y,num2str(closecells(i)),'Color',[1 1 1],'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
                else
                    app.textlabels1(i) = text(app.UIAxes,x,y,num2str(closecells(i)),'Color',[1 1 1],'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
                end
            end
            
            %update ROI # edit fields
            if app.curcell1>0
                app.ROIbeforeEditField.Value = num2str(curcell);
            else
                app.ROIafterEditField.Value = num2str(curcell);
                matching1 = find(app.roiinfo1(:,3)==curcell);
                if ~isempty(matching1)
                    app.ROIbeforeEditField.Value = num2str(matching1(1));
                else
                    app.ROIbeforeEditField.Value = num2str(NaN);
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% ask user for experiment directory
%             app.expDir = uigetdir("","Select an analyzed experiment directory");
            app.expDir = "C:\Users\misaa\Desktop\2022-10-17 14-50 22_08_30_31 full20x_M_APP_ly6g_mouse_R_spot_2";
            %just for testing purposes so I dont have to click every time
            
            %% load images
            %load suite2p data of before dataset
            suite2p = load(fullfile(app.expDir,"1","suite2p","suite2p","plane0","Fall.mat"));
            app.iscell1 = suite2p.iscell(:,1); %iscell: classifier for good ROIs
            app.stat1 = suite2p.stat; %stat: statistics for each cell (to be used for ROI drawing)
            
            %load suite2p data of after dataset
            suite2p = load(fullfile(app.expDir,"2","suite2p","suite2p","plane0","Fall.mat"));
            app.iscell2 = suite2p.iscell(:,1); %iscell: classifier for good ROIs
            app.stat2 = suite2p.stat; %stat: statistics for each cell (to be used for ROI drawing)
            
            %load gcamp images (avg and max ch2)
            avg1 = imread(fullfile(app.expDir,"1","MC functional","AVG_CH2_functional.tif"));
            max1 = imread(fullfile(app.expDir,"1","MC functional","MAX_CH2_functional.tif"));
            avg2 = imread(fullfile(app.expDir,"2","MC functional","AVG_CH2_functional.tif"));
            max2 = imread(fullfile(app.expDir,"2","MC functional","MAX_CH2_functional.tif"));
            
            %load eyfp/mruby2 images
            yfp1 = imread(fullfile(app.expDir,"1","eyfp_unmixed.tif"));
            mruby1 = imread(fullfile(app.expDir,"1","mruby_unmixed.tif"));
            yfp2 = imread(fullfile(app.expDir,"2","eyfp_unmixed.tif"));
            mruby2 = imread(fullfile(app.expDir,"2","mruby_unmixed.tif"));
            
            %% plot all images
            %plot ROIs
            roisimage = double(zeros(size(avg1)));
            numCells = sum(app.iscell1);
            colors = hsv(length(app.iscell1));
            stat = app.stat1;
            goodcells = find(app.iscell1);
            for c = 1:numCells
                cell = goodcells(c);
                roiimage = double(zeros(size(avg1)));
                x = stat{cell}.xpix;
                y = stat{cell}.ypix;
                l = stat{cell}.lam; %brightness
                roiimage(sub2ind(size(roiimage),x,y)) = l;
                roiimage = roiimage.*permute(colors(c,:),[1 3 2]);
                roisimage = roisimage+roiimage;
            end
            roisimage = roisimage/prctile(roisimage,99,"all");
            image(app.UIAxes,roisimage); %plot the image
            axis(app.UIAxes,'off')
            xlim(app.UIAxes,[0.5 size(roisimage,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes,[0.5 size(roisimage,1)-0.5])
            app.centers1 = nan(numCells,2);
            for c = 1:numCells
                cell = goodcells(c);
                app.centers1(c,:) = stat{cell}.med;
            end
            
            roisimage = double(zeros(size(avg2)));
            numCells = sum(app.iscell2);
            colors = hsv(length(app.iscell2));
            stat = app.stat2;
            goodcells = find(app.iscell2);
            for c = 1:numCells
                cell = goodcells(c);
                roiimage = double(zeros(size(avg2)));
                x = stat{cell}.xpix;
                y = stat{cell}.ypix;
                l = stat{cell}.lam; %brightness
                roiimage(sub2ind(size(roiimage),x,y)) = l;
                roiimage = roiimage.*permute(colors(c,:),[1 3 2]);
                roisimage = roisimage+roiimage;
            end
            roisimage = roisimage/prctile(roisimage,99,"all");
            image(app.UIAxes_6,roisimage); %plot the image
            axis(app.UIAxes_6,'off')
            xlim(app.UIAxes_6,[0.5 size(roisimage,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_6,[0.5 size(roisimage,1)-0.5])
            app.centers2 = nan(numCells,2);
            for c = 1:numCells
                cell = goodcells(c);
                app.centers2(c,:) = stat{cell}.med;
            end
            
            %plot gcamp
            %create multicolor image of avg/max (avg in green, max in blue)
            gcamp = double(avg1); %convert to floating point (interpreted by image() as 0-1 brightness)
            gcamp = gcamp/prctile(gcamp,99,"all"); %scale to 0-1ish
            gcamp = repmat(gcamp,[1 1 3]); %repeat for all 3 color channels
            gcamp(:,:,3) = double(max1);
            gcamp(:,:,3) = gcamp(:,:,3)/prctile(gcamp(:,:,3),99,"all");
            gcamp(:,:,1) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_5,gcamp); %plot the image
            axis(app.UIAxes_5,'off')
            xlim(app.UIAxes_5,[0.5 size(gcamp,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_5,[0.5 size(gcamp,1)-0.5])
            
            gcamp = double(avg2); %convert to floating point (interpreted by image() as 0-1 brightness)
            gcamp = gcamp/prctile(gcamp,99,"all"); %scale to 0-1ish
            gcamp = repmat(gcamp,[1 1 3]); %repeat for all 3 color channels
            gcamp(:,:,3) = double(max2);
            gcamp(:,:,3) = gcamp(:,:,3)/prctile(gcamp(:,:,3),99,"all");
            gcamp(:,:,1) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_4,gcamp); %plot the image
            axis(app.UIAxes_4,'off')
            xlim(app.UIAxes_4,[0.5 size(gcamp,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_4,[0.5 size(gcamp,1)-0.5])
            
            %plot eyfp
            yfp1 = double(yfp1); %convert to floating point (interpreted by image() as 0-1 brightness)
            yfp1 =yfp1/prctile(yfp1,99,"all"); %scale to 0-1ish
            yfp1 = repmat(yfp1,[1 1 3]); %repeat for all 3 color channels
            yfp1(:,:,3) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_3,yfp1); %plot the image
            axis(app.UIAxes_3,'off')
            xlim(app.UIAxes_3,[0.5 size(yfp1,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_3,[0.5 size(yfp1,1)-0.5])
            
            yfp2 = double(yfp2); %convert to floating point (interpreted by image() as 0-1 brightness)
            yfp2 = yfp2/prctile(yfp2,99,"all"); %scale to 0-1ish
            yfp2 = repmat(yfp2,[1 1 3]); %repeat for all 3 color channels
            yfp2(:,:,3) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_2,yfp2); %plot the image
            axis(app.UIAxes_2,'off')
            xlim(app.UIAxes_2,[0.5 size(yfp2,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_2,[0.5 size(yfp2,1)-0.5])
            
            %plot mruby2
            mruby1 = double(mruby1); %convert to floating point (interpreted by image() as 0-1 brightness)
            mruby1 = mruby1/prctile(mruby1,99,"all"); %scale to 0-1ish
            mruby1 = repmat(mruby1,[1 1 3]); %repeat for all 3 color channels
            mruby1(:,:,[2 3]) = 0; %erase green and blue channels
            image(app.UIAxes_7,mruby1); %plot the image
            axis(app.UIAxes_7,'off')
            xlim(app.UIAxes_7,[0.5 size(mruby1,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_7,[0.5 size(mruby1,1)-0.5])
            
            mruby2 = double(mruby2); %convert to floating point (interpreted by image() as 0-1 brightness)
            mruby2 = mruby2/prctile(mruby2,99,"all"); %scale to 0-1ish
            mruby2 = repmat(mruby2,[1 1 3]); %repeat for all 3 color channels
            mruby2(:,:,[2 3]) = 0; %erase green and blue channels
            image(app.UIAxes_8,mruby2); %plot the image
            axis(app.UIAxes_8,'off')
            xlim(app.UIAxes_8,[0.5 size(mruby2,2)-0.5]) %zoom in on image size
            ylim(app.UIAxes_8,[0.5 size(mruby2,1)-0.5])
            
            %% pick 1st cell
            app.goodcells1 = find(app.iscell1);
            app.goodcells2 = find(app.iscell2);
            app.roiinfo1 = nan(length(app.goodcells1),3);
            app.roiinfo2 = nan(length(app.goodcells2),3);
            app.curcell1 = 1;
            app.curcell2 = 0;
            app.zoom2cell(); %get x,y coord of cell, zoom in to cell
            app.ROIbeforeEditField.Value = num2str(app.curcell1);
            app.ROIafterEditField.Value = num2str(nan);
            app.beforeLabel.FontSize = 16;
            app.beforeLabel.FontWeight = 'bold';
            app.donecells1=0;
            
            %update labels
            app.Label.Text = [num2str(app.curcell1) '/' num2str(length(app.goodcells1))];
            app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
            app.ROIbeforeEditField.Editable = 'off';
            
        end

        % Button pushed function: SetandcontinueButton
        function SetandcontinueButtonPushed(app, event)
            if app.donecells1==0
                app.roiinfo1(app.goodcells1(app.curcell1),1) = app.goodcells1(app.curcell1); %cell# of current dataset
                if app.NeitherButton.Value
                    color = 4;
                elseif app.eYFPButton.Value
                    color = 1;
                elseif app.mRuby2Button.Value
                    color = 2;
                elseif app.BothButton.Value
                    color = 3;
                elseif app.UnknownButton.Value
                    color = 5;
                end
                app.roiinfo1(app.goodcells1(app.curcell1),2) = color;% color of cell (1=eyfp, 2=mruby, 3=both, 4=neither, 5=unknown)
                if isnan(str2double(app.ROIafterEditField.Value))
                    app.roiinfo1(app.goodcells1(app.curcell1),3) = NaN;
                else
                    app.roiinfo1(app.goodcells1(app.curcell1),3) = app.goodcells2(str2double(app.ROIafterEditField.Value)); % cell# of other dataset
                end
                app.curcell1 = app.curcell1+1;
                app.NeitherButton.Value = 1;
                app.ROIafterEditField.Value = 'NaN';
                app.Label.Text = [num2str(app.curcell1) '/' num2str(length(app.goodcells1))];
                if app.curcell1>length(app.goodcells1)
                    app.donecells1 = 1;
                    app.curcell1 = NaN;
                    app.Label.Text = [num2str(length(app.goodcells1)) '/' num2str(length(app.goodcells1))];
                    app.ROIbeforeEditField.Editable = 'on';
                    app.curcell2 = 1;
                    app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
                    app.ROIafterEditField.Editable = 'off';
                    app.afterLabel.FontSize = 16;
                    app.afterLabel.FontWeight = 'bold';
                    app.beforeLabel.FontSize = 12;
                    app.beforeLabel.FontWeight = 'normal';
                end
            else
                app.roiinfo2(app.goodcells2(app.curcell2),1) = app.goodcells2(app.curcell2); %cell# of current dataset
                if app.NeitherButton.Value
                    color = 4;
                elseif app.eYFPButton.Value
                    color = 1;
                elseif app.mRuby2Button.Value
                    color = 2;
                elseif app.BothButton.Value
                    color = 3;
                elseif app.UnknownButton.Value
                    color = 5;
                end
                app.roiinfo2(app.goodcells2(app.curcell2),2) = color;% color of cell (1=eyfp, 2=mruby, 3=both, 4=neither, 5=unknown)
                if isnan(str2double(app.ROIbeforeEditField.Value))
                    app.roiinfo2(app.goodcells2(app.curcell2),3) = NaN;
                else
                    app.roiinfo2(app.goodcells2(app.curcell2),3) = str2double(app.ROIbeforeEditField.Value); % cell# of other dataset
                end
                app.curcell2 = app.curcell2+1;
                if app.curcell2>length(app.goodcells2)
                    roi_info_1 = app.roiinfo1;
                    roi_info_2 = app.roiinfo2;
                    save(fullfile(app.expDir,'roi_info.mat'),'roi_info_1','roi_info_2')
                    msgbox(['ROI info saved as "' fullfile(app.expDir,'roi_info.mat') '"']);
                    app.delete
                    return;
                end
                app.NeitherButton.Value = 1;
                app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
                while any(app.roiinfo1(:,3)==app.goodcells2(app.curcell2))
                    found = find(app.roiinfo1(:,3)==app.goodcells2(app.curcell2));
                    app.roiinfo2(app.goodcells2(app.curcell2),:) = fliplr(app.roiinfo1(found,:));
                    app.curcell2 = app.curcell2+1;
                    if app.curcell2>length(app.goodcells2)
                        roi_info_1 = app.roiinfo1;
                        roi_info_2 = app.roiinfo2;
                        save(fullfile(app.expDir,'roi_info.mat'),'roi_info_1','roi_info_2')
                        msgbox(['ROI info saved as "' fullfile(app.expDir,'roi_info.mat') '"']);
                        app.delete
                        return;
                    end
                end
                app.ROIbeforeEditField.Value = 'NaN';
                app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
                
            end
            if isvalid(app)
                zoom2cell(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.ROILabel = uilabel(app.UIFigure);
            app.ROILabel.Text = 'ROI';
            app.GCaMPLabel = uilabel(app.UIFigure);
            app.GCaMPLabel.Text = 'GCaMP';
            app.eYFPLabel = uilabel(app.UIFigure);
            app.eYFPLabel.Text = 'eYFP';
            app.mRuby2Label = uilabel(app.UIFigure);
            app.mRuby2Label.Text = 'mRuby2';
            app.anatomicallabelButtonGroup = uibuttongroup(app.UIFigure);
            app.anatomicallabelButtonGroup.Title = 'anatomical label';
            app.eYFPButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.eYFPButton.Text = 'eYFP';
            app.mRuby2Button = uiradiobutton(app.anatomicallabelButtonGroup);
            app.mRuby2Button.Text = 'mRuby2';
            app.BothButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.BothButton.Text = 'Both';
            app.NeitherButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.NeitherButton.Text = 'Neither';
            app.NeitherButton.Value = true;
            app.UnknownButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.ROIbeforeEditFieldLabel = uilabel(app.UIFigure);
            app.ROIbeforeEditFieldLabel.Text = 'ROI # (before)';
            app.ROIbeforeEditField = uieditfield(app.UIFigure, 'text');
            app.ROIbeforeEditField.Value = '0';
            app.ROIafterEditFieldLabel = uilabel(app.UIFigure);
            app.ROIafterEditFieldLabel.Text = 'ROI # (after)';
            app.ROIafterEditField = uieditfield(app.UIFigure, 'text');
            app.ROIafterEditField.Value = 'NaN';
            app.beforeLabel = uilabel(app.UIFigure);
            app.beforeLabel.Text = 'before';
            app.afterLabel = uilabel(app.UIFigure);
            app.afterLabel.Text = 'after';
            app.SetandcontinueButton = uibutton(app.UIFigure, 'push');
            app.SetandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetandcontinueButtonPushed, true);
            app.SetandcontinueButton.Text = 'Set and continue';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellLinker_Part3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end